classdef AEM_Lk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AutomatedKineticInductanceExtractionLabel  matlab.ui.control.Label
        AEMLkLabel              matlab.ui.control.Label
        KineticInductanceAccuracyListBox  matlab.ui.control.ListBox
        KineticInductanceAccuracyListBoxLabel  matlab.ui.control.Label
        FilenameEditField       matlab.ui.control.EditField
        FilenameEditFieldLabel  matlab.ui.control.Label
        ImporttxtFileButton     matlab.ui.control.Button
        StartAutomationButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        Filename;
        Accuracy;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImporttxtFileButton
        function ImporttxtFileButtonPushed(app, event)
            filename= uigetfile('*.txt');
            figure(app.UIFigure);
            app.Filename =filename;
            app.FilenameEditField.Value = filename;
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            app.Filename = app.FilenameEditField.Value;
            
        end

        % Value changed function: KineticInductanceAccuracyListBox
        function KineticInductanceAccuracyListBoxValueChanged(app, event)
            
            input= app.KineticInductanceAccuracyListBox.Value;

            if input == "1 pH/sq"
                app.Accuracy = 1;

            elseif input == "0.1 pH/sq"
                app.Accuracy = 0.1;

            elseif input == "0.01 pH/sq"
                app.Accuracy = 0.01;

            elseif input == "0.001 pH/sq"
                app.Accuracy = 0.001;


            end

        end

        % Button pushed function: StartAutomationButton
        function StartAutomationButtonPushed(app, event)
            

            if numel(app.Accuracy)==0
                app.Accuracy=1;

            end
            AEM_LkFunc(app.Filename, app.Accuracy);

            disp("Kinetic Inductance Extraction Completed!");
            disp("Data stored in Kinetic Inductance Data File.txt");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartAutomationButton = uibutton(app.UIFigure, 'push');
            app.StartAutomationButton.ButtonPushedFcn = createCallbackFcn(app, @StartAutomationButtonPushed, true);
            app.StartAutomationButton.Text = 'Start Automation';
            app.ImporttxtFileButton = uibutton(app.UIFigure, 'push');
            app.ImporttxtFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImporttxtFileButtonPushed, true);
            app.ImporttxtFileButton.Text = 'Import .txt File';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.KineticInductanceAccuracyListBoxLabel = uilabel(app.UIFigure);
            app.KineticInductanceAccuracyListBoxLabel.Text = 'Kinetic Inductance Accuracy(+/-)';
            app.KineticInductanceAccuracyListBox = uilistbox(app.UIFigure);
            app.KineticInductanceAccuracyListBox.Items = {'1 pH/sq', '0.1 pH/sq', '0.01 pH/sq', '0.001 pH/sq'};
            app.KineticInductanceAccuracyListBox.ValueChangedFcn = createCallbackFcn(app, @KineticInductanceAccuracyListBoxValueChanged, true);
            app.KineticInductanceAccuracyListBox.Value = '1 pH/sq';
            app.AEMLkLabel = uilabel(app.UIFigure);
            app.AEMLkLabel.Text = 'AEM-Lk';
            app.AutomatedKineticInductanceExtractionLabel = uilabel(app.UIFigure);
            app.AutomatedKineticInductanceExtractionLabel.Text = {'Automated '; 'Kinetic Inductance '; 'Extraction'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AEM_Lk

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end